classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ModeMenu                   matlab.ui.container.Menu
        CalibrationMenu            matlab.ui.container.Menu
        sensorsMenu                matlab.ui.container.Menu
        pumpsMenu                  matlab.ui.container.Menu
        valvesMenu                 matlab.ui.container.Menu
        ControlMenu                matlab.ui.container.Menu
        ControlPanel               matlab.ui.container.Panel
        MPCPanel                   matlab.ui.container.Panel
        HEditField                 matlab.ui.control.NumericEditField
        HEditFieldLabel            matlab.ui.control.Label
        R2EditField                matlab.ui.control.NumericEditField
        R2EditFieldLabel           matlab.ui.control.Label
        R1EditField                matlab.ui.control.NumericEditField
        R1EditFieldLabel           matlab.ui.control.Label
        S3EditField                matlab.ui.control.NumericEditField
        S3EditFieldLabel           matlab.ui.control.Label
        S2EditField                matlab.ui.control.NumericEditField
        S2EditFieldLabel           matlab.ui.control.Label
        S1EditField                matlab.ui.control.NumericEditField
        S1EditFieldLabel           matlab.ui.control.Label
        BSMIMOPanel                matlab.ui.container.Panel
        K2EditField                matlab.ui.control.NumericEditField
        K2EditFieldLabel           matlab.ui.control.Label
        K1EditField                matlab.ui.control.NumericEditField
        K1EditFieldLabel           matlab.ui.control.Label
        KzEditField                matlab.ui.control.NumericEditField
        KzEditFieldLabel           matlab.ui.control.Label
        BSSISOPanel                matlab.ui.container.Panel
        KEditField                 matlab.ui.control.NumericEditField
        KEditFieldLabel            matlab.ui.control.Label
        IOMIMOPanel                matlab.ui.container.Panel
        Kp2EditField_2             matlab.ui.control.NumericEditField
        Kp2EditField_2Label        matlab.ui.control.Label
        Kp1EditField_2             matlab.ui.control.NumericEditField
        Kp1EditField_2Label        matlab.ui.control.Label
        IOSISOPanel                matlab.ui.container.Panel
        KpEditField_2              matlab.ui.control.NumericEditField
        KpEditField_2Label         matlab.ui.control.Label
        PIMIMOPanel                matlab.ui.container.Panel
        Ki2EditField               matlab.ui.control.NumericEditField
        Ki2EditFieldLabel          matlab.ui.control.Label
        Kp2EditField               matlab.ui.control.NumericEditField
        Kp2EditFieldLabel          matlab.ui.control.Label
        Kp1EditField               matlab.ui.control.NumericEditField
        Kp1EditFieldLabel          matlab.ui.control.Label
        Ki1EditField               matlab.ui.control.NumericEditField
        Ki1EditFieldLabel          matlab.ui.control.Label
        PISISOPanel                matlab.ui.container.Panel
        KiEditField                matlab.ui.control.NumericEditField
        KiEditFieldLabel           matlab.ui.control.Label
        KpEditField                matlab.ui.control.NumericEditField
        KpEditFieldLabel           matlab.ui.control.Label
        RLPanel                    matlab.ui.container.Panel
        Label_20                   matlab.ui.control.Label
        ControllerParametersLabel  matlab.ui.control.Label
        StopButton_4               matlab.ui.control.Button
        StartButton_4              matlab.ui.control.Button
        ReferenceLabel             matlab.ui.control.Label
        T3EditField                matlab.ui.control.NumericEditField
        T3EditFieldLabel           matlab.ui.control.Label
        T2EditField                matlab.ui.control.NumericEditField
        T2EditFieldLabel           matlab.ui.control.Label
        T1EditField                matlab.ui.control.NumericEditField
        T1EditFieldLabel           matlab.ui.control.Label
        ControllerDropDown         matlab.ui.control.DropDown
        ControllerLabel            matlab.ui.control.Label
        SensorPanel                matlab.ui.container.Panel
        StopButton                 matlab.ui.control.Button
        confirmButton_9            matlab.ui.control.Button
        confirmButton_8            matlab.ui.control.Button
        confirmButton_7            matlab.ui.control.Button
        confirmButton_6            matlab.ui.control.Button
        confirmButton_5            matlab.ui.control.Button
        confirmButton_4            matlab.ui.control.Button
        confirmButton_3            matlab.ui.control.Button
        confirmButton_2            matlab.ui.control.Button
        confirmButton_1            matlab.ui.control.Button
        Label_18                   matlab.ui.control.Label
        Label_17                   matlab.ui.control.Label
        Label_16                   matlab.ui.control.Label
        Label_15                   matlab.ui.control.Label
        Label_14                   matlab.ui.control.Label
        Label_13                   matlab.ui.control.Label
        Label_12                   matlab.ui.control.Label
        Label_11                   matlab.ui.control.Label
        Label_10                   matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        SensorVoltageAxes          matlab.ui.control.UIAxes
        ValvePanel                 matlab.ui.container.Panel
        StopButton_3               matlab.ui.control.StateButton
        StartButton_3              matlab.ui.control.StateButton
        Label_19                   matlab.ui.control.Label
        ValveVoltageAxes           matlab.ui.control.UIAxes
        PumpPanel                  matlab.ui.container.Panel
        SetLabel                   matlab.ui.control.Label
        StopButton_2               matlab.ui.control.StateButton
        StartButton_2              matlab.ui.control.StateButton
        PumpVoltageAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
            PumpPlotTimer
            PumpLine1
            PumpLine2
            PumpStartTime
            ValvePlotTimer
            ValveLine1
            ValveLine2
            ValveLine3
            ValveStartTime
            SensorPlotTimer
            SensorLine1
            SensorLine2
            SensorLine3
            SensorStartTime

    end
    
    methods (Access = private)
        
        function recordPoint(app, level, button)
        
            mdl = "Copy_2_of_calibration_sensors";
        
            blockName = sprintf("conflevel%d", level);
            blockPath = mdl + "/" + blockName;
        
            simStatus = get_param(mdl, "SimulationStatus");
        
            if ~strcmp(simStatus, "running")
                uialert(app.UIFigure, ...
                    "Please start Sensor Calibration first.", ...
                    "Simulation not running");
                return
            end
        
            set_param(blockPath, "Value", "1");
            pause(0.2);
            set_param(blockPath, "Value", "0");
        
            button.Text = "Recorded";
            button.Enable = "off";
        
        end

        function updateSensorPlot(app)
        
            mdl = "Copy_2_of_calibration_sensors";
        
            if ~bdIsLoaded(mdl)
                return
            end
        
            if ~strcmp(get_param(mdl, "SimulationStatus"), "running")
                return
            end
        
            try
        
                blk1 = mdl + "/GUI_ADC1";
                blk2 = mdl + "/GUI_ADC2";
                blk3 = mdl + "/GUI_ADC3";
        
                rto1 = get_param(blk1, "RuntimeObject");
                rto2 = get_param(blk2, "RuntimeObject");
                rto3 = get_param(blk3, "RuntimeObject");
        
                adc1 = double(rto1.OutputPort(1).Data);
                adc2 = double(rto2.OutputPort(1).Data);
                adc3 = double(rto3.OutputPort(1).Data);
        
                t = toc(app.SensorStartTime);
        
                addpoints(app.SensorLine1, t, adc1);
                addpoints(app.SensorLine2, t, adc3);
                addpoints(app.SensorLine3, t, adc2);
        
                window = 30;
        
                if t <= window
                    app.SensorVoltageAxes.XLim = [0 window];
                else
                    app.SensorVoltageAxes.XLim = [t-window t];
                end
        
                drawnow limitrate
        
            catch ME
        
                disp("updateSensorPlot ERROR:");
                disp(ME.message);
        
            end
        
        end

        function updatePumpPlot(app)
        
            mdl = "Copy_of_calibration_pumps";
        
            if ~bdIsLoaded(mdl)
                return
            end
        
            if ~strcmp(get_param(mdl, "SimulationStatus"), "running")
                return
            end
        
            try
        
                blk1 = mdl + "/GUI_H1";
                blk3 = mdl + "/GUI_H3";
        
                rto1 = get_param(blk1, "RuntimeObject");
                rto3 = get_param(blk3, "RuntimeObject");
        
                h1 = double(rto1.OutputPort(1).Data);
                h3 = double(rto3.OutputPort(1).Data);
        
                fprintf("h1 = %.3f V, adc3 = %.3f V\n", ...
                    h1, h3);
        
                t = toc(app.PumpStartTime);
        
                addpoints(app.PumpLine1, t, h1);
                addpoints(app.PumpLine2, t, h3);
        
                window = 30;
        
                if t <= window
                    app.PumpVoltageAxes.XLim = [0 window];
                else
                    app.PumpVoltageAxes.XLim = [t-window t];
                end
        
                drawnow limitrate
        
            catch ME
        
                disp("updatePumpPlot ERROR:");
                disp(ME.message);
        
            end
        
        end

        function updateValvePlot(app)

            mdl = "Copy_of_calibration_valves";

            if ~bdIsLoaded(mdl)
                return
            end

            if ~strcmp(get_param(mdl, "SimulationStatus"), "running")
                return
            end

            try

                blk1 = mdl + "/GUI_H1";
                blk2 = mdl + "/GUI_H2";
                blk3 = mdl + "/GUI_H3";

                rto1 = get_param(blk1, "RuntimeObject");
                rto2 = get_param(blk2, "RuntimeObject");
                rto3 = get_param(blk3, "RuntimeObject");

                h1 = double(rto1.OutputPort(1).Data);
                h2 = double(rto2.OutputPort(1).Data);
                h3 = double(rto3.OutputPort(1).Data);

                t = toc(app.ValveStartTime);

                addpoints(app.ValveLine1, t, h1);
                addpoints(app.ValveLine2, t, h2);
                addpoints(app.ValveLine3, t, h3);

                window = 30;

                if t <= window
                    app.ValveVoltageAxes.XLim = [0 window];
                else
                    app.ValveVoltageAxes.XLim = [t-window t];
                end

                drawnow limitrate

            catch ME

                disp("updateValvePlot ERROR:");
                disp(ME.message);

            end

        end
        function setControllerParameter(app, paramName, value)

            mdl = "Copy_of_control_main";
            blk = mdl + "/Variant Subsystem";

            try
                if ~bdIsLoaded(mdl)
                    load_system(mdl);
                end

                set_param(blk, paramName, num2str(value));

            catch ME
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Controller Parameter Error");
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: sensorsMenu
        function sensorsMenuSelected(app, event)
            app.ValvePanel.Visible = "off";
            app.PumpPanel.Visible   = "off";
            app.SensorPanel.Visible  = "on";
            app.ControlPanel.Visible = "off";
        end

        % Menu selected function: pumpsMenu
        function pumpsMenuSelected(app, event)
            app.ValvePanel.Visible = "off";
            app.PumpPanel.Visible   = "on";
            app.SensorPanel.Visible  = "off";
            app.ControlPanel.Visible = "off";
        end

        % Menu selected function: valvesMenu
        function valvesMenuSelected(app, event)
            app.ValvePanel.Visible = "on";
            app.PumpPanel.Visible   = "off";
            app.SensorPanel.Visible  = "off";
            app.ControlPanel.Visible = "off";
        end

        % Menu selected function: ControlMenu
        function ControlMenuSelected(app, event)
            app.ValvePanel.Visible = "off";
            app.PumpPanel.Visible   = "off";
            app.SensorPanel.Visible  = "off";
            app.ControlPanel.Visible = "on";
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            mdl = "Copy_2_of_calibration_sensors";
        
            try
                load_system(mdl);
        
                set_param(mdl, "StopTime", "inf");
        
                set_param(mdl, ...
                    "SimulationCommand", ...
                    "start");
        
                pause(0.3);
        
                status = get_param(mdl, "SimulationStatus");
        
                if strcmp(status, "running")
        
                    app.StartButton.Text = "Running";
                    app.StartButton.Enable = "off";
        
                    cla(app.SensorVoltageAxes);
        
                    app.SensorLine1 = animatedline( ...
                        app.SensorVoltageAxes, ...
                        "LineWidth", 1.5, ...
                        "Color", [0 0.4470 0.7410], ...
                        "DisplayName", "Tank 1");
        
                    app.SensorLine2 = animatedline( ...
                        app.SensorVoltageAxes, ...
                        "LineWidth", 1.5, ...
                        "Color", [0.8500 0.3250 0.0980], ...
                        "DisplayName", "Tank 2");
        
                    app.SensorLine3 = animatedline( ...
                        app.SensorVoltageAxes, ...
                        "LineWidth", 1.5, ...
                        "Color", [0.4660 0.6740 0.1880], ...
                        "DisplayName", "Tank 3");
        
                    xlabel(app.SensorVoltageAxes, "Time (s)");
                    ylabel(app.SensorVoltageAxes, "Voltage (V)");
        
                    title(app.SensorVoltageAxes, ...
                        "Real-Time Sensor Voltage");
        
                    ylim(app.SensorVoltageAxes, [0 3.3]);
                    xlim(app.SensorVoltageAxes, [0 30]);
        
                    grid(app.SensorVoltageAxes, "on");
        
                    legend(app.SensorVoltageAxes, ...
                        "show", ...
                        "Location", "best");
        
                    app.SensorStartTime = tic;
        
                    if ~isempty(app.SensorPlotTimer) && ...
                            isvalid(app.SensorPlotTimer)
        
                        stop(app.SensorPlotTimer);
                        delete(app.SensorPlotTimer);
        
                    end
        
                    app.SensorPlotTimer = timer( ...
                        "ExecutionMode", "fixedSpacing", ...
                        "Period", 1, ...
                        "TimerFcn", @(~,~) app.updateSensorPlot());
        
                    start(app.SensorPlotTimer);
        
                else
        
                    app.StartButton.Text = "Start";
                    app.StartButton.Enable = "on";
        
                    uialert(app.UIFigure, ...
                        "Model status: " + string(status), ...
                        "Simulation Error");
        
                end
        
            catch ME
        
                app.StartButton.Text = "Start";
                app.StartButton.Enable = "on";
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Start Error");
        
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            mdl = "Copy_2_of_calibration_sensors";
        
            try
        
                if ~isempty(app.SensorPlotTimer) && ...
                        isvalid(app.SensorPlotTimer)
        
                    stop(app.SensorPlotTimer);
                    delete(app.SensorPlotTimer);
                    app.SensorPlotTimer = [];
        
                end
        
                if bdIsLoaded(mdl)
        
                    status = get_param(mdl, "SimulationStatus");
        
                    if strcmp(status, "running")
                        set_param(mdl, ...
                            "SimulationCommand", ...
                            "stop");
                    end
        
                end
        
                app.StartButton.Text = "Start";
                app.StartButton.Enable = "on";
        
            catch ME
        
                app.StartButton.Text = "Start";
                app.StartButton.Enable = "on";
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Sensor Stop Error");
        
            end
        end

        % Button pushed function: confirmButton_1
        function confirmButton_1Pushed(app, event)
            app.recordPoint(1, app.confirmButton_1);
        end

        % Button pushed function: confirmButton_2
        function confirmButton_2Pushed(app, event)
            app.recordPoint(2, app.confirmButton_2);
        end

        % Button pushed function: confirmButton_3
        function confirmButton_3Pushed(app, event)
            app.recordPoint(3, app.confirmButton_3);
        end

        % Button pushed function: confirmButton_4
        function confirmButton_4Pushed(app, event)
            app.recordPoint(4, app.confirmButton_4);
        end

        % Button pushed function: confirmButton_5
        function confirmButton_5Pushed(app, event)
            app.recordPoint(5, app.confirmButton_5);
        end

        % Button pushed function: confirmButton_6
        function confirmButton_6Pushed(app, event)
            app.recordPoint(6, app.confirmButton_6);
        end

        % Button pushed function: confirmButton_7
        function confirmButton_7Pushed(app, event)
            app.recordPoint(7, app.confirmButton_7);
        end

        % Button pushed function: confirmButton_8
        function confirmButton_8Pushed(app, event)
            app.recordPoint(8, app.confirmButton_8);
        end

        % Button pushed function: confirmButton_9
        function confirmButton_9Pushed(app, event)
            app.recordPoint(9, app.confirmButton_9);
        end

        % Value changed function: StartButton_2
        function StartButton_2ValueChanged(app, event)
            mdl = "Copy_of_calibration_pumps";
        
            try
        
                load_system(mdl);
        
                set_param(mdl, "BlockReduction", "off");
        
                set_param(mdl, "StopTime", "inf");
        
                set_param(mdl, ...
                    "SimulationCommand", ...
                    "start");
        
                pause(0.3);
        
                status = get_param(mdl, "SimulationStatus");
        
                if strcmp(status, "running")
        
                    app.StartButton_2.Text = "Running";
                    app.StartButton_2.Enable = "off";
        
                    cla(app.PumpVoltageAxes);
        
                    app.PumpLine1 = animatedline( ...
                        app.PumpVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0 0.4470 0.7410], ...
                        "DisplayName", "Tank 1");
                    
                    app.PumpLine2 = animatedline( ...
                        app.PumpVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0.8500 0.3250 0.0980], ...
                        "DisplayName", "Tank 2");
        
                    xlabel(app.PumpVoltageAxes, "Time (s)");
                    ylabel(app.PumpVoltageAxes, "Voltage (V)");
                    title(app.PumpVoltageAxes, ...
                        "Real-Time Sensor Voltage");
        
                    ylim(app.PumpVoltageAxes, [0 3.3]);
                    xlim(app.PumpVoltageAxes, [0 30]);
        
                    grid(app.PumpVoltageAxes, "on");
        
                    legend(app.PumpVoltageAxes, ...
                        "show", ...
                        "Location", "best");
        
                    app.PumpStartTime = tic;
        
                    if ~isempty(app.PumpPlotTimer) && ...
                            isvalid(app.PumpPlotTimer)
        
                        stop(app.PumpPlotTimer);
                        delete(app.PumpPlotTimer);
                    end
        
                    app.PumpPlotTimer = timer( ...
                        "ExecutionMode", "fixedSpacing", ...
                        "Period", 1, ...
                        "TimerFcn", @(~,~) app.updatePumpPlot());
        
                    start(app.PumpPlotTimer);
        
                else
        
                    app.StartButton_2.Text = "Start";
                    app.StartButton_2.Enable = "on";
        
                    uialert(app.UIFigure, ...
                        "Model status: " + string(status), ...
                        "Pump Calibration Error");
        
                end
        
            catch ME
        
                app.StartButton_2.Text = "Start";
                app.StartButton_2.Enable = "on";
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Pump Start Error");
        
            end
        end

        % Value changed function: StopButton_2
        function StopButton_2ValueChanged(app, event)
            mdl = "Copy_of_calibration_pumps";
        
            try
        
                if ~isempty(app.PumpPlotTimer) && ...
                        isvalid(app.PumpPlotTimer)
        
                    stop(app.PumpPlotTimer);
                    delete(app.PumpPlotTimer);
        
                    app.PumpPlotTimer = [];
        
                end
        
                if bdIsLoaded(mdl)
        
                    status = get_param(mdl, ...
                        "SimulationStatus");
        
                    if ~strcmp(status, "stopped")
        
                        set_param(mdl, ...
                            "SimulationCommand", ...
                            "stop");
        
                    end
        
                end
        
                app.StartButton_2.Text = "Start";
                app.StartButton_2.Enable = "on";
        
            catch ME
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Pump Stop Error");
        
            end

        end

        % Value changed function: StartButton_3
        function StartButton_3ValueChanged(app, event)
            mdl = "Copy_of_calibration_valves";
        
            try
        
                load_system(mdl);
        
                set_param(mdl, "BlockReduction", "off");
        
                set_param(mdl, "StopTime", "inf");
        
                set_param(mdl, ...
                    "SimulationCommand", ...
                    "start");
        
                pause(0.3);
        
                status = get_param(mdl, "SimulationStatus");
        
                if strcmp(status, "running")
        
                    app.StartButton_3.Text = "Running";
                    app.StartButton_3.Enable = "off";
        
                    cla(app.ValveVoltageAxes);
        
                    app.ValveLine1 = animatedline( ...
                        app.ValveVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0 0.4470 0.7410], ...
                        "DisplayName", "Tank 1");
        
                    app.ValveLine2 = animatedline( ...
                        app.ValveVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0.8500 0.3250 0.0980], ...
                        "DisplayName", "Tank 2");
        
                    app.ValveLine3 = animatedline( ...
                        app.ValveVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0.4660 0.6740 0.1880], ...
                        "DisplayName", "Tank 3");
        
                    xlabel(app.ValveVoltageAxes, "Time (s)");
                    ylabel(app.ValveVoltageAxes, "Voltage (V)");
        
                    title(app.ValveVoltageAxes, ...
                        "Real-Time Sensor Voltage");
        
                    ylim(app.ValveVoltageAxes, [0 3.3]);
                    xlim(app.ValveVoltageAxes, [0 30]);
        
                    grid(app.ValveVoltageAxes, "on");
        
                    legend(app.ValveVoltageAxes, ...
                        "show", ...
                        "Location", "best");
        
                    app.ValveStartTime = tic;
        
                    if ~isempty(app.ValvePlotTimer) && ...
                            isvalid(app.ValvePlotTimer)
        
                        stop(app.ValvePlotTimer);
                        delete(app.ValvePlotTimer);
        
                    end
        
                    app.ValvePlotTimer = timer( ...
                        "ExecutionMode", "fixedSpacing", ...
                        "Period", 1, ...
                        "TimerFcn", @(~,~) app.updateValvePlot());
        
                    start(app.ValvePlotTimer);
        
                else
        
                    app.StartButton_3.Text = "Start";
                    app.StartButton_3.Enable = "on";
        
                    uialert(app.UIFigure, ...
                        "Model status: " + string(status), ...
                        "Valve Calibration Error");
        
                end
        
            catch ME
        
                app.StartButton_3.Text = "Start";
                app.StartButton_3.Enable = "on";
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Valve Start Error");
        
            end
        end

        % Value changed function: StopButton_3
        function StopButton_3ValueChanged(app, event)
            mdl = "Copy_of_calibration_valves";
        
            try
        
                if ~isempty(app.ValvePlotTimer) && ...
                        isvalid(app.ValvePlotTimer)
        
                    stop(app.ValvePlotTimer);
                    delete(app.ValvePlotTimer);
        
                    app.ValvePlotTimer = [];
        
                end
        
                if bdIsLoaded(mdl)
        
                    status = get_param(mdl, "SimulationStatus");
        
                    if ~strcmp(status, "stopped")
        
                        set_param(mdl, ...
                            "SimulationCommand", ...
                            "stop");
        
                    end
        
                end
        
                app.StartButton_3.Text = "Start";
                app.StartButton_3.Enable = "on";
        
            catch ME
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Valve Stop Error");
        
            end
        end

        % Value changed function: ControllerDropDown
        function ControllerDropDownValueChanged(app, event)
    % Hide all panels
    app.RLPanel.Visible = "off";
    app.MPCPanel.Visible = "off";
    app.BSMIMOPanel.Visible = "off";
    app.BSSISOPanel.Visible = "off";
    app.IOMIMOPanel.Visible = "off";
    app.IOSISOPanel.Visible = "off";
    app.PIMIMOPanel.Visible = "off";
    app.PISISOPanel.Visible = "off";

    controller = app.ControllerDropDown.Value;

    % Select panel + corresponding Simulink Mask value
    switch controller

        case "PI_SISO"
            app.PISISOPanel.Visible = "on";
            maskMode = "PI SISO";

        case "PI_MIMO"
            app.PIMIMOPanel.Visible = "on";
            maskMode = "PI MIMO";

        case "IO_linearization_SISO"
            app.IOSISOPanel.Visible = "on";
            maskMode = "IO Linearization SISO";

        case "IO_linearization_MIMO"
            app.IOMIMOPanel.Visible = "on";
            maskMode = "IO Linearization MIMO";

        case "Backstepping_SISO"
            app.BSSISOPanel.Visible = "on";
            maskMode = "Backstepping SISO";

        case "Backstepping_MIMO"
            app.BSMIMOPanel.Visible = "on";
            maskMode = "Backstepping MIMO";

        case "MPC"
            app.MPCPanel.Visible = "on";
            maskMode = "MPC";

        case "RL"
            app.RLPanel.Visible = "on";
            maskMode = "RL";

        otherwise
            return
    end

    % Write selection to Simulink Mask
    mdl = "Copy_of_control_main";
    blk = mdl + "/Variant Subsystem";

    try

        if ~bdIsLoaded(mdl)
            load_system(mdl);
        end

        set_param(blk, "ctrl_mode", maskMode);

    catch ME

        uialert(app.UIFigure, ...
            ME.message, ...
            "Controller Selection Error");

    end
        end

        % Value changed function: T1EditField
        function T1EditFieldValueChanged(app, event)
            app.setControllerParameter("r1", app.T1EditField.Value);
        end

        % Value changed function: T2EditField
        function T2EditFieldValueChanged(app, event)
            app.setControllerParameter("r2", app.T2EditField.Value);
        end

        % Value changed function: T3EditField
        function T3EditFieldValueChanged(app, event)
            app.setControllerParameter("r3", app.T3EditField.Value);
        end

        % Value changed function: KpEditField
        function KpEditFieldValueChanged(app, event)
            app.setControllerParameter("Kp_PI", app.KpPIEditField.Value);
        end

        % Value changed function: KiEditField
        function KiEditFieldValueChanged(app, event)
            app.setControllerParameter("Ki_PI", app.KiPIEditField.Value);
        end

        % Value changed function: Kp1EditField
        function Kp1EditFieldValueChanged(app, event)
            app.setControllerParameter("Kp1_PI", ...
                app.Kp1PIEditField.Value);
        end

        % Value changed function: Ki1EditField
        function Ki1EditFieldValueChanged(app, event)
            app.setControllerParameter("Ki1_PI", ...
                app.Ki1PIEditField.Value);
        end

        % Value changed function: Kp2EditField
        function Kp2EditFieldValueChanged(app, event)
            app.setControllerParameter("Kp2_PI", ...
                app.Kp2PIEditField.Value);
        end

        % Value changed function: Ki2EditField
        function Ki2EditFieldValueChanged(app, event)
            app.setControllerParameter("Ki2_PI", ...
                app.Ki2PIEditField.Value);
        end

        % Value changed function: KpEditField_2
        function KpEditField_2ValueChanged(app, event)
            app.setControllerParameter("Kp_IO", ...
                app.KpIOEditField.Value);
        end

        % Value changed function: Kp1EditField_2
        function Kp1EditField_2ValueChanged(app, event)
            app.setControllerParameter("Kp1_IO", ...
                app.Kp1IOEditField.Value);
        end

        % Value changed function: Kp2EditField_2
        function Kp2EditField_2ValueChanged(app, event)
            app.setControllerParameter("Kp2_IO", ...
                app.Kp2IOEditField.Value);
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            app.setControllerParameter("K_BS", ...
                app.KBSEditField.Value);
        end

        % Value changed function: KzEditField
        function KzEditFieldValueChanged(app, event)
            app.setControllerParameter("Kz_BS", ...
                app.KzBSEditField.Value);
        end

        % Value changed function: K1EditField
        function K1EditFieldValueChanged(app, event)
            app.setControllerParameter("K1_BS", ...
                app.K1BSEditField.Value);
        end

        % Value changed function: K2EditField
        function K2EditFieldValueChanged(app, event)
            app.setControllerParameter("K2_BS", ...
                app.K2BSEditField.Value);
        end

        % Value changed function: S1EditField
        function S1EditFieldValueChanged(app, event)
            app.setControllerParameter("S1", ...
                app.S1MPCEditField.Value);
        end

        % Value changed function: S2EditField
        function S2EditFieldValueChanged(app, event)
            app.setControllerParameter("S2", ...
                app.S2MPCEditField.Value);
        end

        % Value changed function: S3EditField
        function S3EditFieldValueChanged(app, event)
            app.setControllerParameter("S3", ...
                app.S3MPCEditField.Value);
        end

        % Value changed function: R1EditField
        function R1EditFieldValueChanged(app, event)
            app.setControllerParameter("R1_MPC", ...
                app.R1MPCEditField.Value);
        end

        % Value changed function: R2EditField
        function R2EditFieldValueChanged(app, event)
            app.setControllerParameter("R2_MPC", ...
                app.R2MPCEditField.Value);
        end

        % Value changed function: HEditField
        function HEditFieldValueChanged(app, event)
            app.setControllerParameter("H", ...
                app.HMPCEditField.Value);
        end

        % Button pushed function: StartButton_4
        function StartButton_4Pushed(app, event)
            mdl = "Copy_of_control_main";
        
            try
                if ~bdIsLoaded(mdl)
                    load_system(mdl);
                end
        
                set_param(mdl, "StopTime", "inf");
        
                status = get_param(mdl, "SimulationStatus");
        
                if strcmp(status, "stopped")
                    set_param(mdl, "SimulationCommand", "start");
                end
        
                pause(0.3);
        
                status = get_param(mdl, "SimulationStatus");
        
                if strcmp(status, "running")
        
                    app.StartButton_4.Text = "Running";
                    app.StartButton_4.Enable = "off";
        
                    app.ControllerDropDown.Enable = "off";
        
                else
        
                    app.StartButton_4.Text = "Start";
                    app.StartButton_4.Enable = "on";
        
                    uialert(app.UIFigure, ...
                        "Model status: " + string(status), ...
                        "Controller Start Error");
                end
        
            catch ME
        
                app.StartButton_4.Text = "Start";
                app.StartButton_4.Enable = "on";
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Controller Start Error");
        
            end
        end

        % Button pushed function: StopButton_4
        function StopButton_4Pushed(app, event)
            mdl = "Copy_of_control_main";
        
            try
        
                if bdIsLoaded(mdl)
        
                    status = get_param(mdl, "SimulationStatus");
        
                    if strcmp(status, "running") || strcmp(status, "paused")
                        set_param(mdl, "SimulationCommand", "stop");
                    end
        
                end
        
                app.StartButton_4.Text = "Start";
                app.StartButton_4.Enable = "on";
        
                app.ControllerDropDown.Enable = "on";
        
            catch ME
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Controller Stop Error");
        
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModeMenu = uimenu(app.UIFigure);
            app.ModeMenu.Text = 'Mode';
            app.CalibrationMenu = uimenu(app.ModeMenu);
            app.CalibrationMenu.Text = 'Calibration';
            app.sensorsMenu = uimenu(app.CalibrationMenu);
            app.sensorsMenu.MenuSelectedFcn = createCallbackFcn(app, @sensorsMenuSelected, true);
            app.sensorsMenu.Text = 'sensors';
            app.pumpsMenu = uimenu(app.CalibrationMenu);
            app.pumpsMenu.MenuSelectedFcn = createCallbackFcn(app, @pumpsMenuSelected, true);
            app.pumpsMenu.Text = 'pumps';
            app.valvesMenu = uimenu(app.CalibrationMenu);
            app.valvesMenu.MenuSelectedFcn = createCallbackFcn(app, @valvesMenuSelected, true);
            app.valvesMenu.Text = 'valves';
            app.ControlMenu = uimenu(app.ModeMenu);
            app.ControlMenu.MenuSelectedFcn = createCallbackFcn(app, @ControlMenuSelected, true);
            app.ControlMenu.Text = 'Control';
            app.PumpPanel = uipanel(app.UIFigure);
            app.PumpVoltageAxes = uiaxes(app.PumpPanel);
            xlabel(app.PumpVoltageAxes, 'Time ')
            ylabel(app.PumpVoltageAxes, 'Height')
            zlabel(app.PumpVoltageAxes, 'Z')
            app.PumpVoltageAxes.XLim = [0 30];
            app.PumpVoltageAxes.YLim = [0 3.3];
            app.PumpVoltageAxes.XTick = [];
            app.PumpVoltageAxes.XTickLabel = '';
            app.PumpVoltageAxes.YTick = [];
            app.PumpVoltageAxes.YTickLabel = '';
            app.StartButton_2 = uibutton(app.PumpPanel, 'state');
            app.StartButton_2.ValueChangedFcn = createCallbackFcn(app, @StartButton_2ValueChanged, true);
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.PumpPanel, 'state');
            app.StopButton_2.ValueChangedFcn = createCallbackFcn(app, @StopButton_2ValueChanged, true);
            app.StopButton_2.Text = 'Stop';
            app.SetLabel = uilabel(app.PumpPanel);
            app.SetLabel.Text = 'Set the water level to 10 10 10 and press Start.';
            app.ValvePanel = uipanel(app.UIFigure);
            app.ValveVoltageAxes = uiaxes(app.ValvePanel);
            xlabel(app.ValveVoltageAxes, 'Time')
            ylabel(app.ValveVoltageAxes, 'Height')
            zlabel(app.ValveVoltageAxes, 'Z')
            app.ValveVoltageAxes.XLim = [0 30];
            app.ValveVoltageAxes.YLim = [0 3.3];
            app.ValveVoltageAxes.XTick = [];
            app.ValveVoltageAxes.YTick = [];
            app.ValveVoltageAxes.YTickLabel = '';
            app.Label_19 = uilabel(app.ValvePanel);
            app.Label_19.Text = 'Set the water level to 40,30,20 and press start';
            app.StartButton_3 = uibutton(app.ValvePanel, 'state');
            app.StartButton_3.ValueChangedFcn = createCallbackFcn(app, @StartButton_3ValueChanged, true);
            app.StartButton_3.Text = 'Start';
            app.StopButton_3 = uibutton(app.ValvePanel, 'state');
            app.StopButton_3.ValueChangedFcn = createCallbackFcn(app, @StopButton_3ValueChanged, true);
            app.StopButton_3.Text = 'Stop';
            app.SensorPanel = uipanel(app.UIFigure);
            app.SensorVoltageAxes = uiaxes(app.SensorPanel);
            xlabel(app.SensorVoltageAxes, 'Time')
            ylabel(app.SensorVoltageAxes, 'Voltage')
            zlabel(app.SensorVoltageAxes, 'Z')
            app.SensorVoltageAxes.XLim = [0 30];
            app.SensorVoltageAxes.YLim = [0 3.3];
            app.SensorVoltageAxes.XTick = [];
            app.SensorVoltageAxes.YTick = [0 0.5 1 1.5 2 2.5 3 3.3];
            app.SensorVoltageAxes.YTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'; '3.3'};
            app.StartButton = uibutton(app.SensorPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label_10 = uilabel(app.SensorPanel);
            app.Label_10.Text = '10';
            app.Label_11 = uilabel(app.SensorPanel);
            app.Label_11.Text = '15';
            app.Label_12 = uilabel(app.SensorPanel);
            app.Label_12.Text = '20';
            app.Label_13 = uilabel(app.SensorPanel);
            app.Label_13.Text = '25';
            app.Label_14 = uilabel(app.SensorPanel);
            app.Label_14.Text = '30';
            app.Label_15 = uilabel(app.SensorPanel);
            app.Label_15.Text = '35';
            app.Label_16 = uilabel(app.SensorPanel);
            app.Label_16.Text = '40';
            app.Label_17 = uilabel(app.SensorPanel);
            app.Label_17.Text = '45';
            app.Label_18 = uilabel(app.SensorPanel);
            app.Label_18.Text = '50';
            app.confirmButton_1 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_1.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_1Pushed, true);
            app.confirmButton_1.Text = 'confirm';
            app.confirmButton_2 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_2.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_2Pushed, true);
            app.confirmButton_2.Text = 'confirm';
            app.confirmButton_3 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_3.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_3Pushed, true);
            app.confirmButton_3.Text = 'confirm';
            app.confirmButton_4 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_4.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_4Pushed, true);
            app.confirmButton_4.Text = 'confirm';
            app.confirmButton_5 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_5.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_5Pushed, true);
            app.confirmButton_5.Text = 'confirm';
            app.confirmButton_6 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_6.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_6Pushed, true);
            app.confirmButton_6.Text = 'confirm';
            app.confirmButton_7 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_7.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_7Pushed, true);
            app.confirmButton_7.Text = 'confirm';
            app.confirmButton_8 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_8.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_8Pushed, true);
            app.confirmButton_8.Text = 'confirm';
            app.confirmButton_9 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_9.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_9Pushed, true);
            app.confirmButton_9.Text = 'confirm';
            app.StopButton = uibutton(app.SensorPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControllerLabel = uilabel(app.ControlPanel);
            app.ControllerLabel.Text = 'Controller ';
            app.ControllerDropDown = uidropdown(app.ControlPanel);
            app.ControllerDropDown.Items = {'none', 'PI_SISO', 'PI_MIMO', 'IO_linearization_SISO', 'IO_linearization_MIMO', 'Backstepping_SISO', 'Backstepping_MIMO', 'MPC', 'RL'};
            app.ControllerDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllerDropDownValueChanged, true);
            app.ControllerDropDown.Value = 'none';
            app.T1EditFieldLabel = uilabel(app.ControlPanel);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.ControlPanel, 'numeric');
            app.T1EditField.ValueChangedFcn = createCallbackFcn(app, @T1EditFieldValueChanged, true);
            app.T2EditFieldLabel = uilabel(app.ControlPanel);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.ControlPanel, 'numeric');
            app.T2EditField.ValueChangedFcn = createCallbackFcn(app, @T2EditFieldValueChanged, true);
            app.T3EditFieldLabel = uilabel(app.ControlPanel);
            app.T3EditFieldLabel.Text = 'T3';
            app.T3EditField = uieditfield(app.ControlPanel, 'numeric');
            app.T3EditField.ValueChangedFcn = createCallbackFcn(app, @T3EditFieldValueChanged, true);
            app.ReferenceLabel = uilabel(app.ControlPanel);
            app.ReferenceLabel.Text = 'Reference';
            app.StartButton_4 = uibutton(app.ControlPanel, 'push');
            app.StartButton_4.ButtonPushedFcn = createCallbackFcn(app, @StartButton_4Pushed, true);
            app.StartButton_4.Text = 'Start';
            app.StopButton_4 = uibutton(app.ControlPanel, 'push');
            app.StopButton_4.ButtonPushedFcn = createCallbackFcn(app, @StopButton_4Pushed, true);
            app.StopButton_4.Text = 'Stop';
            app.ControllerParametersLabel = uilabel(app.ControlPanel);
            app.ControllerParametersLabel.Text = 'Controller Parameters';
            app.RLPanel = uipanel(app.ControlPanel);
            app.RLPanel.Visible = 'off';
            app.Label_20 = uilabel(app.RLPanel);
            app.Label_20.Text = 'No additional parameters required';
            app.PISISOPanel = uipanel(app.ControlPanel);
            app.PISISOPanel.Visible = 'off';
            app.KpEditFieldLabel = uilabel(app.PISISOPanel);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.PISISOPanel, 'numeric');
            app.KpEditField.ValueChangedFcn = createCallbackFcn(app, @KpEditFieldValueChanged, true);
            app.KiEditFieldLabel = uilabel(app.PISISOPanel);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.PISISOPanel, 'numeric');
            app.KiEditField.ValueChangedFcn = createCallbackFcn(app, @KiEditFieldValueChanged, true);
            app.PIMIMOPanel = uipanel(app.ControlPanel);
            app.Ki1EditFieldLabel = uilabel(app.PIMIMOPanel);
            app.Ki1EditFieldLabel.Text = 'Ki1';
            app.Ki1EditField = uieditfield(app.PIMIMOPanel, 'numeric');
            app.Ki1EditField.ValueChangedFcn = createCallbackFcn(app, @Ki1EditFieldValueChanged, true);
            app.Kp1EditFieldLabel = uilabel(app.PIMIMOPanel);
            app.Kp1EditFieldLabel.Text = 'Kp1';
            app.Kp1EditField = uieditfield(app.PIMIMOPanel, 'numeric');
            app.Kp1EditField.ValueChangedFcn = createCallbackFcn(app, @Kp1EditFieldValueChanged, true);
            app.Kp2EditFieldLabel = uilabel(app.PIMIMOPanel);
            app.Kp2EditFieldLabel.Text = 'Kp2';
            app.Kp2EditField = uieditfield(app.PIMIMOPanel, 'numeric');
            app.Kp2EditField.ValueChangedFcn = createCallbackFcn(app, @Kp2EditFieldValueChanged, true);
            app.Ki2EditFieldLabel = uilabel(app.PIMIMOPanel);
            app.Ki2EditFieldLabel.Text = 'Ki2';
            app.Ki2EditField = uieditfield(app.PIMIMOPanel, 'numeric');
            app.Ki2EditField.ValueChangedFcn = createCallbackFcn(app, @Ki2EditFieldValueChanged, true);
            app.IOSISOPanel = uipanel(app.ControlPanel);
            app.KpEditField_2Label = uilabel(app.IOSISOPanel);
            app.KpEditField_2Label.Text = 'Kp';
            app.KpEditField_2 = uieditfield(app.IOSISOPanel, 'numeric');
            app.KpEditField_2.ValueChangedFcn = createCallbackFcn(app, @KpEditField_2ValueChanged, true);
            app.IOMIMOPanel = uipanel(app.ControlPanel);
            app.Kp1EditField_2Label = uilabel(app.IOMIMOPanel);
            app.Kp1EditField_2Label.Text = 'Kp1';
            app.Kp1EditField_2 = uieditfield(app.IOMIMOPanel, 'numeric');
            app.Kp1EditField_2.ValueChangedFcn = createCallbackFcn(app, @Kp1EditField_2ValueChanged, true);
            app.Kp2EditField_2Label = uilabel(app.IOMIMOPanel);
            app.Kp2EditField_2Label.Text = 'Kp2';
            app.Kp2EditField_2 = uieditfield(app.IOMIMOPanel, 'numeric');
            app.Kp2EditField_2.ValueChangedFcn = createCallbackFcn(app, @Kp2EditField_2ValueChanged, true);
            app.BSSISOPanel = uipanel(app.ControlPanel);
            app.KEditFieldLabel = uilabel(app.BSSISOPanel);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.BSSISOPanel, 'numeric');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.BSMIMOPanel = uipanel(app.ControlPanel);
            app.KzEditFieldLabel = uilabel(app.BSMIMOPanel);
            app.KzEditFieldLabel.Text = 'Kz';
            app.KzEditField = uieditfield(app.BSMIMOPanel, 'numeric');
            app.KzEditField.ValueChangedFcn = createCallbackFcn(app, @KzEditFieldValueChanged, true);
            app.K1EditFieldLabel = uilabel(app.BSMIMOPanel);
            app.K1EditFieldLabel.Text = 'K1';
            app.K1EditField = uieditfield(app.BSMIMOPanel, 'numeric');
            app.K1EditField.ValueChangedFcn = createCallbackFcn(app, @K1EditFieldValueChanged, true);
            app.K2EditFieldLabel = uilabel(app.BSMIMOPanel);
            app.K2EditFieldLabel.Text = 'K2';
            app.K2EditField = uieditfield(app.BSMIMOPanel, 'numeric');
            app.K2EditField.ValueChangedFcn = createCallbackFcn(app, @K2EditFieldValueChanged, true);
            app.MPCPanel = uipanel(app.ControlPanel);
            app.S1EditFieldLabel = uilabel(app.MPCPanel);
            app.S1EditFieldLabel.Text = 'S1';
            app.S1EditField = uieditfield(app.MPCPanel, 'numeric');
            app.S1EditField.ValueChangedFcn = createCallbackFcn(app, @S1EditFieldValueChanged, true);
            app.S2EditFieldLabel = uilabel(app.MPCPanel);
            app.S2EditFieldLabel.Text = 'S2';
            app.S2EditField = uieditfield(app.MPCPanel, 'numeric');
            app.S2EditField.ValueChangedFcn = createCallbackFcn(app, @S2EditFieldValueChanged, true);
            app.S3EditFieldLabel = uilabel(app.MPCPanel);
            app.S3EditFieldLabel.Text = 'S3';
            app.S3EditField = uieditfield(app.MPCPanel, 'numeric');
            app.S3EditField.ValueChangedFcn = createCallbackFcn(app, @S3EditFieldValueChanged, true);
            app.R1EditFieldLabel = uilabel(app.MPCPanel);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.MPCPanel, 'numeric');
            app.R1EditField.ValueChangedFcn = createCallbackFcn(app, @R1EditFieldValueChanged, true);
            app.R2EditFieldLabel = uilabel(app.MPCPanel);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.MPCPanel, 'numeric');
            app.R2EditField.ValueChangedFcn = createCallbackFcn(app, @R2EditFieldValueChanged, true);
            app.HEditFieldLabel = uilabel(app.MPCPanel);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.MPCPanel, 'numeric');
            app.HEditField.ValueChangedFcn = createCallbackFcn(app, @HEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end